classdef wavGenFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LagLead                    matlab.ui.control.DropDown
        VoltdivEditField           matlab.ui.control.NumericEditField
        TimedivEditField           matlab.ui.control.NumericEditField
        TimeDivKnob                matlab.ui.control.Knob
        TimeDivKnobLabel           matlab.ui.control.Label
        VoltDivKnob                matlab.ui.control.Knob
        VoltDivKnobLabel           matlab.ui.control.Label
        VoltDivDropDown            matlab.ui.control.DropDown
        TimeDivDropDown            matlab.ui.control.DropDown
        FreDropDown                matlab.ui.control.DropDown
        OffsetVEditField           matlab.ui.control.NumericEditField
        OffsetVEditFieldLabel      matlab.ui.control.Label
        PhaseDegreeEditField       matlab.ui.control.NumericEditField
        PhaseDegreeEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField         matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        AmplitudeVEditField        matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel   matlab.ui.control.Label
        WaveformDropDown           matlab.ui.control.DropDown
        WaveformDropDownLabel      matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        Lamp                       matlab.ui.control.Lamp
        Switch                     matlab.ui.control.Switch
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function [unitvalue] = timeUnit(app)
            unit = app.TimeDivDropDown.Value;
            if(strcmp(unit,'s'))
                unitvalue = 1;
            elseif(strcmp(unit,'x1ms'))
                unitvalue = 1/10^3;
            elseif(strcmp(unit,'x10ms'))
                unitvalue = 10/10^3;
            elseif(strcmp(unit,'x100ms'))
                unitvalue = 100/10^3;
            elseif(strcmp(unit,'x10us'))
                unitvalue = 10/10^6;
            elseif(strcmp(unit,'x100us'))
                unitvalue = 100/10^6;
            end
        end
        function xlabelname(app)
            unit = app.TimeDivDropDown.Value;
            app.UIAxes.XLabel.String = "Time("+unit+")";
        end
        
        function [VDvalue] = VDUnit(app)
            unit = app.VoltDivDropDown.Value;
            if(strcmp(unit,'x1V'))
                VDvalue = 1;
            elseif(strcmp(unit,'x10mV'))
                VDvalue = 10/1000;
            elseif(strcmp(unit,'x100mV'))
                VDvalue = 100/1000;
            end
        end

        function [frevalue] = FreUnit(app)
            unit = app.FreDropDown.Value;
            if(strcmp(unit,'x1Hz'))
                frevalue = 1;
            elseif(strcmp(unit,'x10Hz'))
                frevalue = 10;
            elseif(strcmp(unit,'x100Hz'))
                frevalue = 100;
            elseif(strcmp(unit,'x1kHz'))
                frevalue = 100;
            elseif(strcmp(unit,'x10kHz'))
                frevalue = 10000;
            end
        end
        
        function setDefult(app)
            cla(app.UIAxes);
            app.AmplitudeVEditField.Value = 1;
            app.FrequencyEditField.Value = 1;
            app.PhaseDegreeEditField.Value = 0;
            app.OffsetVEditField.Value = 0;
            app.TimeDivDropDown.Value = 's';
            app.VoltDivDropDown.Value = 'x1V';
            app.FreDropDown.Value = 'x1Hz';
            app.TimeDivKnob.Value = 1;
            app.VoltDivKnob.Value = 1;
            app.VoltdivEditField.Value=1;
            app.TimedivEditField.Value=1;
            app.LagLead.Value='Lagging';

            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            xlabelname(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            value = app.Switch.Value;
            amp = app.AmplitudeVEditField.Value ;
            F = app.FrequencyEditField.Value;
            FU = FreUnit(app);
            F = F*FU;
            Td = app.TimeDivKnob.Value;
            t = -1*Td:1/(100*FU): Td;
            TU = timeUnit(app);
            t = t*TU;
            Tperiod = 1/F;
            Omega = (2*pi)/Tperiod; % w
            PhaseAngle = app.PhaseDegreeEditField.Value; 
            PhaseAngle = (PhaseAngle*Omega)/360;
            LL = app.LagLead.Value;
            if strcmp(LL,'Lagging')
                PhaseAngle = PhaseAngle*(-1);
            end
            offset = app.OffsetVEditField.Value;
            Wtype = app.WaveformDropDown.Value;
            VoltDiv = app.VoltDivKnob.Value;
            TimeDive = app.TimeDivKnob.Value;
            
            if strcmp(value,'Off')
                app.Lamp.Color = 'r';
                setDefult(app);
            else
                xlabelname(app);
                app.Lamp.Color = 'g';
                cla(app.UIAxes);
                VU = VDUnit(app);
                VoltDiv = VoltDiv*VU;

                TimeDiv = TimeDive*TU;
                app.UIAxes.YLim = [(-1)*VoltDiv VoltDiv];
                app.UIAxes.XLim = [(-1)*TimeDiv TimeDiv];
                
                if strcmp(Wtype,'Sine')
                    y = amp*(sin(Omega*t+PhaseAngle));
                    plot(app.UIAxes,t,y+offset,'LineWidth',2.0);
                elseif strcmp(Wtype,'Square')
                    sq = 0;
                    for k=1:2:10000
                        sq = sq + (4*amp*sin(k*Omega*t+PhaseAngle*k))/(pi*k);
                    end
                    plot(app.UIAxes,t,sq+offset,'LineWidth',2.0);

                elseif strcmp(Wtype,'Triangular')
                    tri = amp/2;
                    for k = 1:2:10000
                        tri = tri + ((-4*amp)*cos(k*Omega*t+PhaseAngle*k))/(k*pi)^2;
                    end
                    plot(app.UIAxes,t,tri+offset,'LineWidth',2.0);

                elseif strcmp(Wtype,'Sawtooth')
                    sa = amp/2;%0;
                    for k=1:1:10000
                        sa = sa + (amp*-1*sin(k*Omega*t+PhaseAngle*k))/(pi*k);
                    end
                    plot(app.UIAxes,t,sa+offset,'LineWidth',2.0);
                    
                elseif strcmp(Wtype,'Full-wave rectified')
                    full = (2*amp)/pi;
                    for k=1:1:10000
                        full = full +((-4*amp)*cos(k*Omega*t+PhaseAngle*k))/(pi*((4*k*k)-1));
                    end
                    plot(app.UIAxes,t,full+offset,'LineWidth',2.0);
                end
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            SwitchValueChanged(app, event);
        end

        % Callback function
        function PlotButtonPushed(app, event)
            
        end

        % Value changed function: TimeDivKnob
        function TimeDivKnobValueChanged(app, event)
           app.TimedivEditField.Value = app.TimeDivKnob.Value;
           SwitchValueChanged(app, event);
        end

        % Button down function: LeftPanel
        function LeftPanelButtonDown(app, event)
            
        end

        % Value changed function: PhaseDegreeEditField
        function PhaseDegreeEditFieldValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Value changed function: WaveformDropDown
        function WaveformDropDownValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Value changed function: OffsetVEditField
        function OffsetVEditFieldValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Value changed function: AmplitudeVEditField
        function AmplitudeVEditFieldValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Value changed function: TimeDivDropDown
        function TimeDivDropDownValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Value changed function: FreDropDown
        function FreDropDownValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Button down function: RightPanel
        function RightPanelButtonDown(app, event)
            
        end

        % Value changed function: VoltDivKnob
        function VoltDivKnobValueChanged(app, event)
            app.VoltdivEditField.Value = app.VoltDivKnob.Value;
            SwitchValueChanged(app, event);
        end

        % Value changed function: VoltDivDropDown
        function VoltDivDropDownValueChanged(app, event)
            SwitchValueChanged(app, event);
        end

        % Value changed function: TimedivEditField
        function TimedivEditFieldValueChanged(app, event)
            app.TimeDivKnob.Value = app.TimedivEditField.Value;
            SwitchValueChanged(app, event);
        end

        % Value changed function: VoltdivEditField
        function VoltdivEditFieldValueChanged(app, event)
            app.VoltDivKnob.Value = app.VoltdivEditField.Value;
            SwitchValueChanged(app, event);
        end

        % Value changed function: LagLead
        function LagLeadValueChanged(app, event)
           SwitchValueChanged(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {564, 564};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.WaveformDropDownLabel = uilabel(app.LeftPanel);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.LeftPanel);
            app.WaveformDropDown.Items = {'Sine', 'Square', 'Triangular', 'Sawtooth', 'Full-wave rectified'};
            app.WaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformDropDownValueChanged, true);
            app.WaveformDropDown.Value = 'Sine';
            app.AmplitudeVEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudeVEditField.Limits = [0 Inf];
            app.AmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditFieldValueChanged, true);
            app.AmplitudeVEditField.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 1;
            app.PhaseDegreeEditFieldLabel = uilabel(app.LeftPanel);
            app.PhaseDegreeEditFieldLabel.Text = 'Phase (Degree)';
            app.PhaseDegreeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PhaseDegreeEditField.Limits = [0 Inf];
            app.PhaseDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDegreeEditFieldValueChanged, true);
            app.OffsetVEditFieldLabel = uilabel(app.LeftPanel);
            app.OffsetVEditFieldLabel.Text = 'Offset (V)';
            app.OffsetVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OffsetVEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetVEditFieldValueChanged, true);
            app.FreDropDown = uidropdown(app.LeftPanel);
            app.FreDropDown.Items = {'x1Hz', 'x10Hz', 'x100Hz', 'x1kHz', 'x10kHz'};
            app.FreDropDown.ValueChangedFcn = createCallbackFcn(app, @FreDropDownValueChanged, true);
            app.FreDropDown.Value = 'x1Hz';
            app.TimeDivDropDown = uidropdown(app.LeftPanel);
            app.TimeDivDropDown.Items = {'s', 'x1ms', 'x10ms', 'x100ms', 'x10us', 'x100us'};
            app.TimeDivDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDivDropDownValueChanged, true);
            app.TimeDivDropDown.Value = 's';
            app.VoltDivDropDown = uidropdown(app.LeftPanel);
            app.VoltDivDropDown.Items = {'x1V', 'x10mV', 'x100mV'};
            app.VoltDivDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltDivDropDownValueChanged, true);
            app.VoltDivDropDown.Value = 'x1V';
            app.VoltDivKnobLabel = uilabel(app.LeftPanel);
            app.VoltDivKnobLabel.Text = 'Volt /  Div';
            app.VoltDivKnob = uiknob(app.LeftPanel, 'continuous');
            app.VoltDivKnob.Limits = [1 10];
            app.VoltDivKnob.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.VoltDivKnob.ValueChangedFcn = createCallbackFcn(app, @VoltDivKnobValueChanged, true);
            app.VoltDivKnob.Value = 1;
            app.TimeDivKnobLabel = uilabel(app.LeftPanel);
            app.TimeDivKnobLabel.Text = 'Time /  Div';
            app.TimeDivKnob = uiknob(app.LeftPanel, 'continuous');
            app.TimeDivKnob.Limits = [1 10];
            app.TimeDivKnob.ValueChangedFcn = createCallbackFcn(app, @TimeDivKnobValueChanged, true);
            app.TimeDivKnob.Value = 1;
            app.TimedivEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimedivEditField.Limits = [1 10];
            app.TimedivEditField.ValueChangedFcn = createCallbackFcn(app, @TimedivEditFieldValueChanged, true);
            app.TimedivEditField.Value = 1;
            app.VoltdivEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VoltdivEditField.ValueChangedFcn = createCallbackFcn(app, @VoltdivEditFieldValueChanged, true);
            app.VoltdivEditField.Value = 1;
            app.LagLead = uidropdown(app.LeftPanel);
            app.LagLead.Items = {'Lagging', 'Leading'};
            app.LagLead.ValueChangedFcn = createCallbackFcn(app, @LagLeadValueChanged, true);
            app.LagLead.Value = 'Lagging';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @RightPanelButtonDown, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Wave')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Volt')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wavGenFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end